classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TextRecogniser        matlab.ui.Figure
        WczytajobrazButton    matlab.ui.control.Button
        RozpoznajtekstButton  matlab.ui.control.Button
        EditFieldLabel        matlab.ui.control.Label
        EditField             matlab.ui.control.EditField
        UIAxes                matlab.ui.control.UIAxes
        BinaryzacjaButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        obrazek;
        binarny;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: WczytajobrazButton
        function WczytajobrazButtonPushed(app, event)
            [file, path] = uigetfile({'*.png';'*.jpg';'*.bmp';'*.svg';'*.gif'}, 'Wybierz obrazek:');
            figure(app.TextRecogniser)
            filepath=[path file];
            if ~isequal(file,0)
               app.obrazek = double(imread(filepath))/255;
               imshow(app.obrazek,'parent',app.UIAxes);
               app.EditField.Value = '';
            end

        end

        % Button pushed function: RozpoznajtekstButton
        function RozpoznajtekstButtonPushed(app, event)
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Value changing function: EditField
        function EditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Button pushed function: BinaryzacjaButton
        function BinaryzacjaButtonPushed(app, event)
               app.binarny = imbinarize(app.obrazek);
               app.binarny = app.binarny(:,:,1);
               app.binarny= imopen(app.binarny,ones(2));
               imshow(app.binarny,'parent',app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TextRecogniser = uifigure('Visible', 'off');
            app.TextRecogniser.Name = 'MATLAB App';
            app.WczytajobrazButton = uibutton(app.TextRecogniser, 'push');
            app.WczytajobrazButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajobrazButtonPushed, true);
            app.WczytajobrazButton.Text = 'Wczytaj obraz';
            app.RozpoznajtekstButton = uibutton(app.TextRecogniser, 'push');
            app.RozpoznajtekstButton.ButtonPushedFcn = createCallbackFcn(app, @RozpoznajtekstButtonPushed, true);
            app.RozpoznajtekstButton.Text = 'Rozpoznaj tekst';
            app.EditFieldLabel = uilabel(app.TextRecogniser);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.TextRecogniser, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanging, true);
            app.UIAxes = uiaxes(app.TextRecogniser);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.BinaryzacjaButton = uibutton(app.TextRecogniser, 'push');
            app.BinaryzacjaButton.ButtonPushedFcn = createCallbackFcn(app, @BinaryzacjaButtonPushed, true);
            app.BinaryzacjaButton.Text = 'Binaryzacja';
            % Show the figure after all components are created
            app.TextRecogniser.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TextRecogniser)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TextRecogniser)
        end
    end
end